classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        FindValuesButton       matlab.ui.control.Button
        bEditFieldLabel        matlab.ui.control.Label
        bEditField             matlab.ui.control.EditField
        dEditFieldLabel        matlab.ui.control.Label
        dEditField             matlab.ui.control.EditField
        WEditFieldLabel        matlab.ui.control.Label
        WEditField             matlab.ui.control.EditField
        LEditFieldLabel        matlab.ui.control.Label
        LEditField             matlab.ui.control.EditField
        FillThesedetailsLabel  matlab.ui.control.Label
        ResetButton            matlab.ui.control.Button
        CenterPanel            matlab.ui.container.Panel
        Image                  matlab.ui.control.Image
        Wtext                  matlab.ui.control.TextArea
        Ltext                  matlab.ui.control.TextArea
        btext                  matlab.ui.control.TextArea
        dtext                  matlab.ui.control.TextArea
        WLabel                 matlab.ui.control.Label
        LLabel                 matlab.ui.control.Label
        dLabel                 matlab.ui.control.Label
        bLabel                 matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        ZEditFieldLabel        matlab.ui.control.Label
        ztext                  matlab.ui.control.EditField
        MEditFieldLabel        matlab.ui.control.Label
        mtext                  matlab.ui.control.EditField
        Label                  matlab.ui.control.Label
        sigmatext              matlab.ui.control.EditField
        ZTextArea              matlab.ui.control.TextArea
        ZTextArea_2            matlab.ui.control.TextArea
        ZTextArea_3            matlab.ui.control.TextArea
        FormulasUsedLabel      matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
        global bvalue;    
        bvalue = str2double(app.bEditField.Value);
            
        end

        % Value changed function: dEditField
        function dEditFieldValueChanged(app, event)
        global dvalue;
        dvalue = str2double(app.dEditField.Value);
            
        end

        % Value changed function: WEditField
        function WEditFieldValueChanged(app, event)
        global wvalue;
        wvalue = str2double(app.WEditField.Value);
            
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
        global lvalue;
        lvalue = str2double(app.LEditField.Value);          
        end

        % Button pushed function: FindValuesButton
        function FindValuesButtonPushed(app, event)
            global zvalue;
            global mvalue;
            global sigma;
            bvalue = str2double(app.bEditField.Value);
            dvalue = str2double(app.dEditField.Value);
            wvalue = str2double(app.WEditField.Value);
            lvalue = str2double(app.LEditField.Value);          
            zvalue=(bvalue*(dvalue^2))/6.0;
            mvalue= wvalue*lvalue;
            sigma=mvalue/zvalue;
            app.ztext.Value=num2str(zvalue);
            app.mtext.Value=num2str(mvalue);
            app.sigmatext.Value=num2str(sigma);
           
            app.Wtext.Value=num2str(wvalue);
            app.btext.Value=num2str(bvalue);
            app.dtext.Value=num2str(dvalue);
            app.Ltext.Value=num2str(lvalue);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ztext.Value=num2str(0);
            app.mtext.Value=num2str(0);
            app.sigmatext.Value=num2str(0);
            app.bEditField.Value=num2str(0);
            app.dEditField.Value=num2str(0);
            app.WEditField.Value=num2str(0);
            app.LEditField.Value=num2str(0);
            app.Wtext.Value=num2str(0);
            app.btext.Value=num2str(0);
            app.dtext.Value=num2str(0);
            app.Ltext.Value=num2str(0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FindValuesButton = uibutton(app.LeftPanel, 'push');
            app.FindValuesButton.ButtonPushedFcn = createCallbackFcn(app, @FindValuesButtonPushed, true);
            app.FindValuesButton.Text = 'Find Values';
            app.bEditFieldLabel = uilabel(app.LeftPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.LeftPanel, 'text');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.dEditFieldLabel = uilabel(app.LeftPanel);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.LeftPanel, 'text');
            app.dEditField.ValueChangedFcn = createCallbackFcn(app, @dEditFieldValueChanged, true);
            app.WEditFieldLabel = uilabel(app.LeftPanel);
            app.WEditFieldLabel.Text = 'W';
            app.WEditField = uieditfield(app.LeftPanel, 'text');
            app.WEditField.ValueChangedFcn = createCallbackFcn(app, @WEditFieldValueChanged, true);
            app.LEditFieldLabel = uilabel(app.LeftPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.LeftPanel, 'text');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.FillThesedetailsLabel = uilabel(app.LeftPanel);
            app.FillThesedetailsLabel.Text = 'Fill These details';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.CenterPanel);
            app.Image.ImageSource = 'fig1.jpg';
            app.Wtext = uitextarea(app.CenterPanel);
            app.Wtext.Editable = 'off';
            app.Wtext.Enable = 'off';
            app.Wtext.Value = {'W'};
            app.Ltext = uitextarea(app.CenterPanel);
            app.Ltext.Editable = 'off';
            app.Ltext.Enable = 'off';
            app.Ltext.Value = {'L'};
            app.btext = uitextarea(app.CenterPanel);
            app.btext.Editable = 'off';
            app.btext.Enable = 'off';
            app.btext.Value = {'b'};
            app.dtext = uitextarea(app.CenterPanel);
            app.dtext.Editable = 'off';
            app.dtext.Enable = 'off';
            app.dtext.Value = {'d'};
            app.WLabel = uilabel(app.CenterPanel);
            app.WLabel.Text = 'W';
            app.LLabel = uilabel(app.CenterPanel);
            app.LLabel.Text = 'L';
            app.dLabel = uilabel(app.CenterPanel);
            app.dLabel.Text = 'd';
            app.bLabel = uilabel(app.CenterPanel);
            app.bLabel.Text = 'b';
            app.Label_2 = uilabel(app.CenterPanel);
            app.Label_2.Text = {'Q1:A cantilever of length 2m fails when a load of 2kN is applied at the '; ''; 'free end. If the section of the beam is 40x60 mm find stress at the failure.'};
            app.RightPanel = uipanel(app.GridLayout);
            app.ZEditFieldLabel = uilabel(app.RightPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ztext = uieditfield(app.RightPanel, 'text');
            app.ztext.Editable = 'off';
            app.MEditFieldLabel = uilabel(app.RightPanel);
            app.MEditFieldLabel.Text = 'M';
            app.mtext = uieditfield(app.RightPanel, 'text');
            app.mtext.Editable = 'off';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = {'σ'; ''};
            app.sigmatext = uieditfield(app.RightPanel, 'text');
            app.sigmatext.Editable = 'off';
            app.ZTextArea = uitextarea(app.RightPanel);
            app.ZTextArea.Editable = 'off';
            app.ZTextArea.Value = {' Z=(b*(d^2))/6.0'};
            app.ZTextArea_2 = uitextarea(app.RightPanel);
            app.ZTextArea_2.Editable = 'off';
            app.ZTextArea_2.Value = {'M=W*L'};
            app.ZTextArea_3 = uitextarea(app.RightPanel);
            app.ZTextArea_3.Editable = 'off';
            app.ZTextArea_3.Value = {'σ=M/Z'};
            app.FormulasUsedLabel = uilabel(app.RightPanel);
            app.FormulasUsedLabel.Text = 'Formulas Used';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end