classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        WEditFieldLabel   matlab.ui.control.Label
        WEditField        matlab.ui.control.EditField
        wEditFieldLabel   matlab.ui.control.Label
        wEditField        matlab.ui.control.EditField
        LEditFieldLabel   matlab.ui.control.Label
        LEditField        matlab.ui.control.EditField
        Label_2           matlab.ui.control.Label
        sigmaEditField    matlab.ui.control.EditField
        FindValuesButton  matlab.ui.control.Button
        ResetButton       matlab.ui.control.Button
        CenterPanel       matlab.ui.container.Panel
        Image             matlab.ui.control.Image
        Label             matlab.ui.control.Label
        wTextArea_2       matlab.ui.control.TextArea
        xTextArea_3       matlab.ui.control.TextArea
        LTextArea_4       matlab.ui.control.TextArea
        RaTextArea_5      matlab.ui.control.TextArea
        RbTextArea_6      matlab.ui.control.TextArea
        WTextArea         matlab.ui.control.TextArea
        RightPanel        matlab.ui.container.Panel
        MEditFieldLabel   matlab.ui.control.Label
        MEditField        matlab.ui.control.EditField
        ZEditFieldLabel   matlab.ui.control.Label
        ZEditField        matlab.ui.control.EditField
        bEditFieldLabel   matlab.ui.control.Label
        bEditField        matlab.ui.control.EditField
        dEditFieldLabel   matlab.ui.control.Label
        dEditField        matlab.ui.control.EditField
        RbEditFieldLabel  matlab.ui.control.Label
        RbEditField       matlab.ui.control.EditField
        RaEditFieldLabel  matlab.ui.control.Label
        RaEditField       matlab.ui.control.EditField
        b32Z13Label       matlab.ui.control.Label
        d2bLabel          matlab.ui.control.Label
        ZM1000sigmaLabel  matlab.ui.control.Label
        MRbxwxx20Label    matlab.ui.control.Label
        Rbw4W3Label       matlab.ui.control.Label
        RawRbLabel        matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: WEditField
        function WEditFieldValueChanged(app, event)
            value = app.WEditField.Value;
            
        end

        % Value changed function: wEditField
        function wEditFieldValueChanged(app, event)
            value = app.wEditField.Value;
            
        end

        % Value changed function: LEditField
        function LEditFieldValueChanged(app, event)
            value = app.LEditField.Value;
            
        end

        % Value changed function: sigmaEditField
        function sigmaEditFieldValueChanged(app, event)
            value = app.sigmaEditField.Value;
            
        end

        % Button pushed function: FindValuesButton
        function FindValuesButtonPushed(app, event)
             sigmavalue = str2double(app.sigmaEditField.Value);
             Lvalue = str2double(app.LEditField.Value);
             xvalue=4.3125;
             wvalue = str2double(app.wEditField.Value);
             Wvalue = str2double(app.WEditField.Value);
             Rb=(wvalue*sigmavalue*4+Wvalue*3)/sigmavalue;
             Ra=wvalue-Rb;
             M=Rb*xvalue-wvalue*xvalue*xvalue/2.0;
             Z=M*1000/sigmavalue;
             b=((3/2)*Z)^(1/3);
             d=2*b;
             app.RbEditField.Value= num2str(Rb);
             app.RaEditField.Value= num2str(Ra);
             app.MEditField.Value=num2str(M);
             app.ZEditField.Value=num2str(Z);
             app.bEditField.Value=num2str(b);
             app.dEditField.Value=num2str(d);
             app.WTextArea.Value=num2str(Wvalue);
             app.wTextArea_2.Value=num2str(wvalue);
             app.xTextArea_3.Value=num2str(3);
             app.LTextArea_4.Value=num2str(Lvalue);
             app.RaTextArea_5.Value=num2str(Ra);
             app.RbTextArea_6.Value=num2str(Rb);
            
        end

        % Value changed function: WTextArea
        function WTextAreaValueChanged(app, event)
            value = app.WTextArea.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.WEditFieldLabel = uilabel(app.LeftPanel);
            app.WEditFieldLabel.Text = 'W';
            app.WEditField = uieditfield(app.LeftPanel, 'text');
            app.WEditField.ValueChangedFcn = createCallbackFcn(app, @WEditFieldValueChanged, true);
            app.wEditFieldLabel = uilabel(app.LeftPanel);
            app.wEditFieldLabel.Text = 'w';
            app.wEditField = uieditfield(app.LeftPanel, 'text');
            app.wEditField.ValueChangedFcn = createCallbackFcn(app, @wEditFieldValueChanged, true);
            app.LEditFieldLabel = uilabel(app.LeftPanel);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.LeftPanel, 'text');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @LEditFieldValueChanged, true);
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = {'σ'; ''};
            app.sigmaEditField = uieditfield(app.LeftPanel, 'text');
            app.sigmaEditField.ValueChangedFcn = createCallbackFcn(app, @sigmaEditFieldValueChanged, true);
            app.FindValuesButton = uibutton(app.LeftPanel, 'push');
            app.FindValuesButton.ButtonPushedFcn = createCallbackFcn(app, @FindValuesButtonPushed, true);
            app.FindValuesButton.Text = 'Find Values';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.Text = {'Reset'; ''};
            app.CenterPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.CenterPanel);
            app.Image.ImageSource = 'fig2.jpg';
            app.Label = uilabel(app.CenterPanel);
            app.Label.Text = {'Q2:A square beam 20mm X 20mm in section and 2m long is simply '; 'supported at the ends. The beam fails when a point load of 400N'; ' is applied at the centre of the beam. What uniformly distributed '; 'load per meter length will break a cantilever of the same material '; '40mm wide 60mm deep and 3m long?'};
            app.WTextArea = uitextarea(app.CenterPanel);
            app.WTextArea.ValueChangedFcn = createCallbackFcn(app, @WTextAreaValueChanged, true);
            app.WTextArea.Editable = 'off';
            app.WTextArea.Enable = 'off';
            app.WTextArea.Value = {'W'};
            app.wTextArea_2 = uitextarea(app.CenterPanel);
            app.wTextArea_2.Editable = 'off';
            app.wTextArea_2.Enable = 'off';
            app.wTextArea_2.Value = {'w'};
            app.xTextArea_3 = uitextarea(app.CenterPanel);
            app.xTextArea_3.Editable = 'off';
            app.xTextArea_3.Enable = 'off';
            app.xTextArea_3.Value = {'x'};
            app.LTextArea_4 = uitextarea(app.CenterPanel);
            app.LTextArea_4.Editable = 'off';
            app.LTextArea_4.Enable = 'off';
            app.LTextArea_4.Value = {'L'};
            app.RaTextArea_5 = uitextarea(app.CenterPanel);
            app.RaTextArea_5.Editable = 'off';
            app.RaTextArea_5.Enable = 'off';
            app.RaTextArea_5.Value = {'Ra'};
            app.RbTextArea_6 = uitextarea(app.CenterPanel);
            app.RbTextArea_6.Editable = 'off';
            app.RbTextArea_6.Enable = 'off';
            app.RbTextArea_6.Value = {'Rb'};
            app.RightPanel = uipanel(app.GridLayout);
            app.MEditFieldLabel = uilabel(app.RightPanel);
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.RightPanel, 'text');
            app.ZEditFieldLabel = uilabel(app.RightPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.RightPanel, 'text');
            app.bEditFieldLabel = uilabel(app.RightPanel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.RightPanel, 'text');
            app.dEditFieldLabel = uilabel(app.RightPanel);
            app.dEditFieldLabel.Text = 'd';
            app.dEditField = uieditfield(app.RightPanel, 'text');
            app.RbEditFieldLabel = uilabel(app.RightPanel);
            app.RbEditFieldLabel.Text = 'Rb';
            app.RbEditField = uieditfield(app.RightPanel, 'text');
            app.RaEditFieldLabel = uilabel(app.RightPanel);
            app.RaEditFieldLabel.Text = 'Ra';
            app.RaEditField = uieditfield(app.RightPanel, 'text');
            app.b32Z13Label = uilabel(app.RightPanel);
            app.b32Z13Label.Text = 'b=((3/2)*Z)^(1/3)';
            app.d2bLabel = uilabel(app.RightPanel);
            app.d2bLabel.Text = 'd=2*b';
            app.ZM1000sigmaLabel = uilabel(app.RightPanel);
            app.ZM1000sigmaLabel.Text = 'Z=M*1000/sigma';
            app.MRbxwxx20Label = uilabel(app.RightPanel);
            app.MRbxwxx20Label.Text = 'M=Rb*x-w*x*x/2.0';
            app.Rbw4W3Label = uilabel(app.RightPanel);
            app.Rbw4W3Label.Text = {'Rb=(w*σ*4+W*3)/σ'; ''};
            app.RawRbLabel = uilabel(app.RightPanel);
            app.RawRbLabel.Text = {'Ra=w-Rb'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end