classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        rEditFieldLabel    matlab.ui.control.Label
        rEditField         matlab.ui.control.EditField
        REditFieldLabel    matlab.ui.control.Label
        REditField         matlab.ui.control.EditField
        rhoEditFieldLabel  matlab.ui.control.Label
        rhoEditField       matlab.ui.control.EditField
        EEditFieldLabel    matlab.ui.control.Label
        EEditField         matlab.ui.control.EditField
        FindValuesButton   matlab.ui.control.Button
        CenterPanel        matlab.ui.container.Panel
        Image              matlab.ui.control.Image
        RightPanel         matlab.ui.container.Panel
        COM4R3000piLabel   matlab.ui.control.Label
        COMEditFieldLabel  matlab.ui.control.Label
        COMEditField       matlab.ui.control.EditField
        IEditFieldLabel    matlab.ui.control.Label
        IEditField         matlab.ui.control.EditField
        Ipid4640Label      matlab.ui.control.Label
        MEditFieldLabel    matlab.ui.control.Label
        MEditField         matlab.ui.control.EditField
        MIErhoLabel        matlab.ui.control.Label
        EditFieldLabel     matlab.ui.control.Label
        EditField          matlab.ui.control.EditField
        COMErhoLabel       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: FindValuesButton
        function FindValuesButtonPushed(app, event)
            rvalue=str2double(app.rEditField.Value);
            Rvalue=str2double(app.REditField.Value);
            rhovalue=str2double(app.rhoEditField.Value);
            Evalue=str2double(app.EEditField.Value);
            
            COM=4*Rvalue/(3000*3.14);
            d=(Rvalue*2)/1000;
            I=3.14*d^4/64.0;
            M=I*Evalue/rhovalue;
            sigma=COM*Evalue/rhovalue;
            app.COMEditField.Value=num2str(COM);
            app.IEditField.Value=num2str(I);
            app.MEditField.Value=num2str(M);
            app.EditField.Value=num2str(sigma);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.rEditFieldLabel = uilabel(app.LeftPanel);
            app.rEditFieldLabel.Text = 'r';
            app.rEditField = uieditfield(app.LeftPanel, 'text');
            app.REditFieldLabel = uilabel(app.LeftPanel);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.LeftPanel, 'text');
            app.rhoEditFieldLabel = uilabel(app.LeftPanel);
            app.rhoEditFieldLabel.Text = 'rho';
            app.rhoEditField = uieditfield(app.LeftPanel, 'text');
            app.EEditFieldLabel = uilabel(app.LeftPanel);
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.LeftPanel, 'text');
            app.FindValuesButton = uibutton(app.LeftPanel, 'push');
            app.FindValuesButton.ButtonPushedFcn = createCallbackFcn(app, @FindValuesButtonPushed, true);
            app.FindValuesButton.Text = 'Find Values';
            app.CenterPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.CenterPanel);
            app.Image.ImageSource = 'fig5.jpg';
            app.RightPanel = uipanel(app.GridLayout);
            app.COM4R3000piLabel = uilabel(app.RightPanel);
            app.COM4R3000piLabel.Text = 'COM=4*R/(3000*pi)';
            app.COMEditFieldLabel = uilabel(app.RightPanel);
            app.COMEditFieldLabel.Enable = 'off';
            app.COMEditFieldLabel.Text = {'COM'; ''};
            app.COMEditField = uieditfield(app.RightPanel, 'text');
            app.COMEditField.Editable = 'off';
            app.COMEditField.Enable = 'off';
            app.IEditFieldLabel = uilabel(app.RightPanel);
            app.IEditFieldLabel.Enable = 'off';
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.RightPanel, 'text');
            app.IEditField.Editable = 'off';
            app.IEditField.Enable = 'off';
            app.Ipid4640Label = uilabel(app.RightPanel);
            app.Ipid4640Label.Text = 'I=pi*d^4/64.0';
            app.MEditFieldLabel = uilabel(app.RightPanel);
            app.MEditFieldLabel.Enable = 'off';
            app.MEditFieldLabel.Text = 'M';
            app.MEditField = uieditfield(app.RightPanel, 'text');
            app.MEditField.Editable = 'off';
            app.MEditField.Enable = 'off';
            app.MIErhoLabel = uilabel(app.RightPanel);
            app.MIErhoLabel.Text = 'M=I*E/rho';
            app.EditFieldLabel = uilabel(app.RightPanel);
            app.EditFieldLabel.Enable = 'off';
            app.EditFieldLabel.Text = 'σ';
            app.EditField = uieditfield(app.RightPanel, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.COMErhoLabel = uilabel(app.RightPanel);
            app.COMErhoLabel.Text = 'σ=COM*E/rho';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end